classdef mini_detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CorrelationThresholdSlider     matlab.ui.control.Slider
        CorrelationThresholdSliderLabel  matlab.ui.control.Label
        DetectusingtemplateButton      matlab.ui.control.Button
        AcceptandFinishButton          matlab.ui.control.Button
        DetecteventsbyamplitudeButton  matlab.ui.control.Button
        NoiseThresholdSlider           matlab.ui.control.Slider
        NoiseThresholdSliderLabel      matlab.ui.control.Label
        TesttraceDropDown              matlab.ui.control.DropDown
        TesttraceDropDownLabel         matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        trace_number % which trace is currently being plotted
        noise_threshold % noise threshold to exclude peaks
        template_threshold %template matching correlation threshold
        traces % array of traces
        psc_flag %-1 for EPSC, 1 for IPSC
        thresh_events %array of noise-threshold events
        template_events %array of template-based events
        template %template PSC 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, traces, psc_flag, template)
           app.traces = traces; %load in the array of traces
           app.psc_flag = psc_flag; %-1 for EPSC, 1 for IPSCs
           app.TesttraceDropDown.Items = compose('%d',1:size(app.traces,1)); %Set the size of the drop-down menu for selecting traces
           plot(app.UIAxes,app.traces(1,:)); %plot the first trace
           ylim(app.UIAxes, [-100+mean(app.traces(1,:)),100+mean(app.traces(1,:))]); %set the y-axis limits so it's easier to see events
           app.trace_number = 1; %set the initial trace number to run the analysis on
           app.noise_threshold = 10; %set the initial noise threshold
           app.template_threshold = 0.5; %set the initial template matching threshold
           app.template = template; %assign the PSC template
        end

        % Value changed function: TesttraceDropDown
        function TesttraceDropDownValueChanged(app, event)
            %Update the display with a new trace
            app.trace_number = str2double(app.TesttraceDropDown.Value);
            current_trace = app.traces(app.trace_number,:);
            plot(app.UIAxes,current_trace);
            ylim(app.UIAxes, [-100+mean(current_trace),100+mean(current_trace)]);            
        end

        % Value changed function: NoiseThresholdSlider
        function NoiseThresholdSliderValueChanged(app, event)
            app.noise_threshold = app.NoiseThresholdSlider.Value;
            % set the value here for changing the noise threshold
        end

        % Button pushed function: DetecteventsbyamplitudeButton
        function DetecteventsbyamplitudeButtonPushed(app, event)
            %Code here for when the button is pushed - detect events and
            %display on the Axes
            current_trace = app.traces(app.trace_number,:); %get the current trace
            
            %replot to prevent events from stacking up
            plot(app.UIAxes,current_trace);
            ylim(app.UIAxes, [-100+mean(current_trace),100+mean(current_trace)]);  
            
            %%
            events = threshold_detection(current_trace,app.psc_flag,app.noise_threshold);
            app.thresh_events = events; %assign into the app space
            
            %plot the detected events
            hold(app.UIAxes,'on')
            event_time = find(events);
            plot(app.UIAxes,event_time,current_trace(event_time),'*r')

            if ~isempty(app.template_events)
                event_time = find(app.template_events);
                plot(app.UIAxes,event_time,current_trace(event_time),'om')
            end

            hold(app.UIAxes,'off')

            
        end

        % Button pushed function: AcceptandFinishButton
        function AcceptandFinishButtonPushed(app, event)
            assignin('base','noise_threshold',app.noise_threshold) %export the threshold to the base workspace
            assignin('base','template_threshold',app.template_threshold) %export the threshold to the base workspace
            closereq(); %exit out of the threshold detection
        end

        % Value changed function: CorrelationThresholdSlider
        function CorrelationThresholdSliderValueChanged(app, event)
            app.template_threshold = app.CorrelationThresholdSlider.Value;
            
        end

        % Button pushed function: DetectusingtemplateButton
        function DetectusingtemplateButtonPushed(app, event)
            %Code here for when the button is pushed - detect events and
            %display on the Axes
            current_trace = app.traces(app.trace_number,:); %get the current trace
            
            %replot to prevent events from stacking up
            plot(app.UIAxes,current_trace);
            ylim(app.UIAxes, [-100+mean(current_trace),100+mean(current_trace)]);  
            
            %%
            events = template_detection(current_trace,app.template,app.template_threshold,app.psc_flag);
            app.template_events = events; %assign into the app space
            
            %plot the detected events
            hold(app.UIAxes,'on')
            event_time = find(events);
            plot(app.UIAxes,event_time,current_trace(event_time),'om')
               
            if ~isempty(app.thresh_events)
                event_time = find(app.thresh_events);
                plot(app.UIAxes,event_time,current_trace(event_time),'*r')
            end

            hold(app.UIAxes,'off')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Event Detection')
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'Current (pA)')
            app.TesttraceDropDownLabel = uilabel(app.UIFigure);
            app.TesttraceDropDownLabel.Text = 'Test trace';
            app.TesttraceDropDown = uidropdown(app.UIFigure);
            app.TesttraceDropDown.Items = {'1', ''};
            app.TesttraceDropDown.ValueChangedFcn = createCallbackFcn(app, @TesttraceDropDownValueChanged, true);
            app.TesttraceDropDown.Value = '1';
            app.NoiseThresholdSliderLabel = uilabel(app.UIFigure);
            app.NoiseThresholdSliderLabel.Text = {'Noise Threshold'; ''};
            app.NoiseThresholdSlider = uislider(app.UIFigure);
            app.NoiseThresholdSlider.Limits = [0 20];
            app.NoiseThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseThresholdSliderValueChanged, true);
            app.NoiseThresholdSlider.MinorTicks = [];
            app.NoiseThresholdSlider.Value = 10;
            app.DetecteventsbyamplitudeButton = uibutton(app.UIFigure, 'push');
            app.DetecteventsbyamplitudeButton.ButtonPushedFcn = createCallbackFcn(app, @DetecteventsbyamplitudeButtonPushed, true);
            app.DetecteventsbyamplitudeButton.Text = 'Detect events by amplitude';
            app.AcceptandFinishButton = uibutton(app.UIFigure, 'push');
            app.AcceptandFinishButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptandFinishButtonPushed, true);
            app.AcceptandFinishButton.Text = {'Accept and Finish'; ''};
            app.DetectusingtemplateButton = uibutton(app.UIFigure, 'push');
            app.DetectusingtemplateButton.ButtonPushedFcn = createCallbackFcn(app, @DetectusingtemplateButtonPushed, true);
            app.DetectusingtemplateButton.Text = 'Detect using template';
            app.CorrelationThresholdSliderLabel = uilabel(app.UIFigure);
            app.CorrelationThresholdSliderLabel.Text = {'Correlation'; 'Threshold'};
            app.CorrelationThresholdSlider = uislider(app.UIFigure);
            app.CorrelationThresholdSlider.Limits = [0 1];
            app.CorrelationThresholdSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.CorrelationThresholdSlider.MajorTickLabels = {'0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.CorrelationThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @CorrelationThresholdSliderValueChanged, true);
            app.CorrelationThresholdSlider.MinorTicks = [];
            app.CorrelationThresholdSlider.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mini_detection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end