classdef mini_qc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BackButton          matlab.ui.control.Button
        FinishButton        matlab.ui.control.Button
        ProgressLabel       matlab.ui.control.Label
        RejectButton        matlab.ui.control.Button
        AcceptButton        matlab.ui.control.Button
        Current_Event_Axes  matlab.ui.control.UIAxes
        Current_Trace_Axes  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        current_peak = 1; % number of current peak, start at 1
        final_events = []; % List of peaks to keep
        traces % Array of all the traces
        event_inds % Array of the indices for detected events
        current_trace % current trace being analyzed 
        n_current_peaks %number of peaks in the trace currently being analyzed        
        progress_string % to display how far we are
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, traces, event_inds)
            app.traces = traces; %make the traces and event_inds inputs available to the rest of the app functions
            app.event_inds = event_inds;
            app.final_events = zeros(size(app.traces));
            
            app.current_trace = 1; %start with the the first trace
            
            app.current_peak = 1; %start with the first peak
            
            %Get the event times for the first trace
            event_times = find(event_inds(1,:));
                        
            app.n_current_peaks = length(event_times); %establish how many peaks in the first trace
            
            %initialize the current trace plot
            plot(app.Current_Trace_Axes,app.traces(1,:)) %Plot the first trace
            ylim(app.Current_Trace_Axes, [-100+mean(app.traces(1,:)),100+mean(app.traces(1,:))]); %set the y-axis limits so it's easier to see events
            
            hold( app.Current_Trace_Axes, 'on' );            
            plot(app.Current_Trace_Axes,event_times,app.traces(1,event_times),'*')
            plot(app.Current_Trace_Axes, event_times(app.current_peak),app.traces(app.current_trace,event_times(app.current_peak)),'*m')
            
            %Initialize the current event plot
            if event_times(1) < 5000
                plot(app.Current_Event_Axes, event_times(1)-300:event_times(1)+5000, app.traces(1,event_times(1)-300:event_times(1)+5000));
            else 
                plot(app.Current_Event_Axes, event_times(1)-5000:event_times(1)+5000, app.traces(1,event_times(1)-5000:event_times(1)+5000));
            end
            ylim(app.Current_Event_Axes, [-100+mean(app.traces(app.current_trace,:)),100+mean(app.traces(app.current_trace,:))]); %set the y-axis limits so it's easier to see events
            hold(app.Current_Event_Axes, 'on');
            plot(app.Current_Event_Axes, event_times(1),app.traces(1,event_times(1)),'*')
            

        end

        % Button pushed function: RejectButton
        function RejectButtonPushed(app, event)
            %Block of code to remove the current event to the final events
            event_times = find(app.event_inds(app.current_trace,:)); %need to extract the event_time again
            current_event_time = event_times(app.current_peak);
            app.final_events(app.current_trace,current_event_time) = 0;

            app.current_peak = app.current_peak + 1; %advance the peak by one
                                  
            if app.current_peak <= app.n_current_peaks % If the curent peak is below the total number of peaks
                
                event_times = find(app.event_inds(app.current_trace,:)); %recalculate event times - make this a property?
                                
                hold(app.Current_Event_Axes, 'off');
                %plot the new peak, if statements to just handle when peaks
                %are near the beginning or end of a trace                            
                if event_times(app.current_peak) < 5000
                    plot(app.Current_Event_Axes,...
                        event_times(app.current_peak)-300:event_times(app.current_peak)+2500,...
                        app.traces(app.current_trace,event_times(app.current_peak)-300:event_times(app.current_peak)+2500));
                elseif event_times(app.current_peak) >= 5000 & event_times(app.current_peak) <= 90000
                    plot(app.Current_Event_Axes,...
                        event_times(app.current_peak)-2500:event_times(app.current_peak)+2500,...
                        app.traces(app.current_trace,event_times(app.current_peak)-2500:event_times(app.current_peak)+2500));
                elseif event_times(app.current_peak) > 9000
                    plot(app.Current_Event_Axes,...
                        event_times(app.current_peak)-2500:95000,...
                        app.traces(app.current_trace,event_times(app.current_peak)-2500:95000));
                end
                
                ylim(app.Current_Event_Axes, [-100+mean(app.traces(app.current_trace,:)),100+mean(app.traces(app.current_trace,:))]); %set the y-axis limits so it's easier to see events
               
                %Add a marker to the location of the current peak
                hold(app.Current_Event_Axes, 'on');
                plot(app.Current_Event_Axes, event_times(app.current_peak),app.traces(app.current_trace,event_times(app.current_peak)),'*')
                plot(app.Current_Trace_Axes, event_times(app.current_peak),app.traces(app.current_trace,event_times(app.current_peak)),'*m')
                
            elseif  app.current_peak > app.n_current_peaks  %If this advances beyond the nuber of peaks in the current trace, move to the next trace
                
                app.current_trace = app.current_trace + 1; %add +1 to the current trace
                
                if app.current_trace > size(app.traces,1) %All done!
                    assignin('base','final_events',app.final_events) %export the threshold to the base workspace
                    closereq(); %exit out of the threshold detection
                    display('QC done!');
                end
                
                app.current_peak = 1; %reset the current peak to 1
                                                
                %Get the event times for the new current trace
                event_times = find(app.event_inds(app.current_trace,:));
                                
                app.n_current_peaks = length(event_times); %establish how many peaks in the trace
                
                if app.n_current_peaks >= 1 %If there is at least one peak in the current trace
                
                    hold(app.Current_Trace_Axes, 'off');
                    
                    %Plot the new trace with it's event peaks
                    plot(app.Current_Trace_Axes,app.traces(app.current_trace,:)) %Plot the trace
                    ylim(app.Current_Trace_Axes, [-100+mean(app.traces(app.current_trace,:)),100+mean(app.traces(app.current_trace,:))]); %set the y-axis limits so it's easier to see events
            
                    hold( app.Current_Trace_Axes, 'on' );            
                    plot(app.Current_Trace_Axes,event_times,app.traces(app.current_trace,event_times),'*')
                    plot(app.Current_Trace_Axes, event_times(app.current_peak),app.traces(app.current_trace,event_times(app.current_peak)),'*m')
                    
                    %Plot the new event
                    hold(app.Current_Event_Axes, 'off');
                
                        if event_times(app.current_peak) < 5000
                            plot(app.Current_Event_Axes, event_times(app.current_peak)-300:event_times(app.current_peak)+2500, app.traces(app.current_trace,event_times(app.current_peak)-300:event_times(app.current_peak)+2500));
                        else 
                            plot(app.Current_Event_Axes, event_times(app.current_peak)-2500:event_times(app.current_peak)+2500, app.traces(app.current_trace,event_times(app.current_peak)-2500:event_times(app.current_peak)+2500));
                        end
                
                        ylim(app.Current_Event_Axes, [-100+mean(app.traces(app.current_trace,:)),100+mean(app.traces(app.current_trace,:))]); %set the y-axis limits so it's easier to see events
                
                        %Add a marker to the location of the current peak
                        hold(app.Current_Event_Axes, 'on');
                        plot(app.Current_Event_Axes, event_times(app.current_peak),app.traces(app.current_trace,event_times(app.current_peak)),'*')
                
                elseif app.n_current_peaks == 0 %If there is not a peak on the current trace
                    hold(app.Current_Trace_Axes, 'off');
                    
                    %Plot the new trace with it's event peaks
                    plot(app.Current_Trace_Axes,app.traces(app.current_trace,:)) %Plot the trace
                    ylim(app.Current_Trace_Axes, [-100+mean(app.traces(app.current_trace,:)),100+mean(app.traces(app.current_trace,:))]); %set the y-axis limits so it's easier to see events
            
                    cla(app.Current_Event_Axes)                    
                end
            end                
            
            app.progress_string = sprintf('Event %d/%d, Trace %d/%d, %d kept events',app.current_peak,app.n_current_peaks,app.current_trace,size(app.traces,1),sum(sum(app.final_events)));
            app.ProgressLabel.Text = {app.progress_string};
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            %Block of code to add the current event to the final events
            event_times = find(app.event_inds(app.current_trace,:)); %need to extract the event_time again
            current_event_time = event_times(app.current_peak);
            app.final_events(app.current_trace,current_event_time) = 1;
            
            %Continue on as if it was a rejected peak
            app.current_peak = app.current_peak + 1; %advance the peak by one
                                  
            if app.current_peak <= app.n_current_peaks % If the curent peak is below the total number of peaks
                
                event_times = find(app.event_inds(app.current_trace,:)); %recalculate event times - make this a property?
                                
                hold(app.Current_Event_Axes, 'off');
                %plot the new peak, if statements to just handle when peaks
                %are near the beginning or end of a trace                            
                if event_times(app.current_peak) < 5000
                    plot(app.Current_Event_Axes,...
                        event_times(app.current_peak)-300:event_times(app.current_peak)+2500,...
                        app.traces(app.current_trace,event_times(app.current_peak)-300:event_times(app.current_peak)+2500));
                elseif event_times(app.current_peak) >= 5000 & event_times(app.current_peak) <= 90000
                    plot(app.Current_Event_Axes,...
                        event_times(app.current_peak)-2500:event_times(app.current_peak)+2500,...
                        app.traces(app.current_trace,event_times(app.current_peak)-2500:event_times(app.current_peak)+2500));
                elseif event_times(app.current_peak) > 9000
                    plot(app.Current_Event_Axes,...
                        event_times(app.current_peak)-2500:95000,...
                        app.traces(app.current_trace,event_times(app.current_peak)-2500:95000));
                end
                
                ylim(app.Current_Event_Axes, [-100+mean(app.traces(app.current_trace,:)),100+mean(app.traces(app.current_trace,:))]); %set the y-axis limits so it's easier to see events
               
                %Add a marker to the location of the current peak
                hold(app.Current_Event_Axes, 'on');
                plot(app.Current_Event_Axes, event_times(app.current_peak),app.traces(app.current_trace,event_times(app.current_peak)),'*')
                plot(app.Current_Trace_Axes, event_times(app.current_peak),app.traces(app.current_trace,event_times(app.current_peak)),'*m')
                
            elseif  app.current_peak > app.n_current_peaks  %If this advances beyond the nuber of peaks in the current trace, move to the next trace
                
                app.current_trace = app.current_trace + 1; %add +1 to the current trace
                
                if app.current_trace > size(app.traces,1) %All done!
                    assignin('base','final_events',app.final_events) %export the threshold to the base workspace
                    closereq(); %exit out of the threshold detection
                    display('QC done!');
                end
                
                app.current_peak = 1; %reset the current peak to 1
                                                
                %Get the event times for the new current trace
                event_times = find(app.event_inds(app.current_trace,:));
                                
                app.n_current_peaks = length(event_times); %establish how many peaks in the trace
                
                if app.n_current_peaks >= 1 %If there is at least one peak in the current trace
                
                    hold(app.Current_Trace_Axes, 'off');
                    
                    %Plot the new trace with it's event peaks
                    plot(app.Current_Trace_Axes,app.traces(app.current_trace,:)) %Plot the trace
                    ylim(app.Current_Trace_Axes, [-100+mean(app.traces(app.current_trace,:)),100+mean(app.traces(app.current_trace,:))]); %set the y-axis limits so it's easier to see events
            
                    hold( app.Current_Trace_Axes, 'on' );            
                    plot(app.Current_Trace_Axes,event_times,app.traces(app.current_trace,event_times),'*')
                    plot(app.Current_Trace_Axes, event_times(app.current_peak),app.traces(app.current_trace,event_times(app.current_peak)),'*m')
                    
                    %Plot the new event
                    hold(app.Current_Event_Axes, 'off');
                
                        if event_times(app.current_peak) < 5000
                            plot(app.Current_Event_Axes, event_times(app.current_peak)-300:event_times(app.current_peak)+2500, app.traces(app.current_trace,event_times(app.current_peak)-300:event_times(app.current_peak)+2500));
                        else 
                            plot(app.Current_Event_Axes, event_times(app.current_peak)-2500:event_times(app.current_peak)+2500, app.traces(app.current_trace,event_times(app.current_peak)-2500:event_times(app.current_peak)+2500));
                        end
                
                        ylim(app.Current_Event_Axes, [-100+mean(app.traces(app.current_trace,:)),100+mean(app.traces(app.current_trace,:))]); %set the y-axis limits so it's easier to see events
                
                        %Add a marker to the location of the current peak
                        hold(app.Current_Event_Axes, 'on');
                        plot(app.Current_Event_Axes, event_times(app.current_peak),app.traces(app.current_trace,event_times(app.current_peak)),'*')
                
                elseif app.n_current_peaks == 0 %If there is not a peak on the current trace
                    hold(app.Current_Trace_Axes, 'off');
                    
                    %Plot the new trace with it's event peaks
                    plot(app.Current_Trace_Axes,app.traces(app.current_trace,:)) %Plot the trace
                    ylim(app.Current_Trace_Axes, [-100+mean(app.traces(app.current_trace,:)),100+mean(app.traces(app.current_trace,:))]); %set the y-axis limits so it's easier to see events
            
                    cla(app.Current_Event_Axes)                    
                end
            end                
            
            app.progress_string = sprintf('Event %d/%d, Trace %d/%d, %d kept events',app.current_peak,app.n_current_peaks,app.current_trace,size(app.traces,1),sum(sum(app.final_events)));
            app.ProgressLabel.Text = {app.progress_string};
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
              assignin('base','final_events',app.final_events) %export the threshold to the base workspace
              closereq(); %exit out of the threshold detection
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            switch key
                case 'a' %accept
                    app.AcceptButtonPushed(event)
                case 'r' %reject
                    app.RejectButtonPushed(event)
                case 'b' %back
                    app.BackButtonPushed(event)
end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            %Set the current peak back by one, then continue as if it was
            %rejected
            app.current_peak = app.current_peak - 1; %set the peak back by one
                                  
            if app.current_peak <= app.n_current_peaks % If the curent peak is below the total number of peaks
                
                event_times = find(app.event_inds(app.current_trace,:)); %recalculate event times - make this a property?
                                
                hold(app.Current_Event_Axes, 'off');
                %plot the new peak, if statements to just handle when peaks
                %are near the beginning or end of a trace                            
                if event_times(app.current_peak) < 5000
                    plot(app.Current_Event_Axes,...
                        event_times(app.current_peak)-300:event_times(app.current_peak)+2500,...
                        app.traces(app.current_trace,event_times(app.current_peak)-300:event_times(app.current_peak)+2500));
                elseif event_times(app.current_peak) >= 5000 & event_times(app.current_peak) <= 90000
                    plot(app.Current_Event_Axes,...
                        event_times(app.current_peak)-2500:event_times(app.current_peak)+2500,...
                        app.traces(app.current_trace,event_times(app.current_peak)-2500:event_times(app.current_peak)+2500));
                elseif event_times(app.current_peak) > 9000
                    plot(app.Current_Event_Axes,...
                        event_times(app.current_peak)-2500:95000,...
                        app.traces(app.current_trace,event_times(app.current_peak)-2500:95000));
                end
                
                ylim(app.Current_Event_Axes, [-100+mean(app.traces(app.current_trace,:)),100+mean(app.traces(app.current_trace,:))]); %set the y-axis limits so it's easier to see events
               
                %Add a marker to the location of the current peak
                hold(app.Current_Event_Axes, 'on');
                plot(app.Current_Event_Axes, event_times(app.current_peak),app.traces(app.current_trace,event_times(app.current_peak)),'*')
                plot(app.Current_Trace_Axes, event_times(app.current_peak),app.traces(app.current_trace,event_times(app.current_peak)),'*m')
                
            elseif  app.current_peak > app.n_current_peaks  %If this advances beyond the nuber of peaks in the current trace, move to the next trace
                
                app.current_trace = app.current_trace + 1; %add +1 to the current trace
                
                if app.current_trace > size(app.traces,1) %All done!
                    assignin('base','final_events',app.final_events) %export the threshold to the base workspace
                    closereq(); %exit out of the threshold detection
                    display('QC done!');
                end
                
                app.current_peak = 1; %reset the current peak to 1
                                                
                %Get the event times for the new current trace
                event_times = find(app.event_inds(app.current_trace,:));
                                
                app.n_current_peaks = length(event_times); %establish how many peaks in the trace
                
                if app.n_current_peaks >= 1 %If there is at least one peak in the current trace
                
                    hold(app.Current_Trace_Axes, 'off');
                    
                    %Plot the new trace with it's event peaks
                    plot(app.Current_Trace_Axes,app.traces(app.current_trace,:)) %Plot the trace
                    ylim(app.Current_Trace_Axes, [-100+mean(app.traces(app.current_trace,:)),100+mean(app.traces(app.current_trace,:))]); %set the y-axis limits so it's easier to see events
            
                    hold( app.Current_Trace_Axes, 'on' );            
                    plot(app.Current_Trace_Axes,event_times,app.traces(app.current_trace,event_times),'*')
                    plot(app.Current_Trace_Axes, event_times(app.current_peak),app.traces(app.current_trace,event_times(app.current_peak)),'*m')
                    
                    %Plot the new event
                    hold(app.Current_Event_Axes, 'off');
                
                        if event_times(app.current_peak) < 5000
                            plot(app.Current_Event_Axes, event_times(app.current_peak)-300:event_times(app.current_peak)+2500, app.traces(app.current_trace,event_times(app.current_peak)-300:event_times(app.current_peak)+2500));
                        else 
                            plot(app.Current_Event_Axes, event_times(app.current_peak)-2500:event_times(app.current_peak)+2500, app.traces(app.current_trace,event_times(app.current_peak)-2500:event_times(app.current_peak)+2500));
                        end
                
                        ylim(app.Current_Event_Axes, [-100+mean(app.traces(app.current_trace,:)),100+mean(app.traces(app.current_trace,:))]); %set the y-axis limits so it's easier to see events
                
                        %Add a marker to the location of the current peak
                        hold(app.Current_Event_Axes, 'on');
                        plot(app.Current_Event_Axes, event_times(app.current_peak),app.traces(app.current_trace,event_times(app.current_peak)),'*')
                
                elseif app.n_current_peaks == 0 %If there is not a peak on the current trace
                    hold(app.Current_Trace_Axes, 'off');
                    
                    %Plot the new trace with it's event peaks
                    plot(app.Current_Trace_Axes,app.traces(app.current_trace,:)) %Plot the trace
                    ylim(app.Current_Trace_Axes, [-100+mean(app.traces(app.current_trace,:)),100+mean(app.traces(app.current_trace,:))]); %set the y-axis limits so it's easier to see events
            
                    cla(app.Current_Event_Axes)                    
                end
            end                
            
            app.progress_string = sprintf('Event %d/%d, Trace %d/%d, %d kept events',app.current_peak,app.n_current_peaks,app.current_trace,size(app.traces,1),sum(sum(app.final_events)));
            app.ProgressLabel.Text = {app.progress_string};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Current_Trace_Axes = uiaxes(app.UIFigure);
            title(app.Current_Trace_Axes, 'Current Trace')
            xlabel(app.Current_Trace_Axes, 'Time (msec)')
            ylabel(app.Current_Trace_Axes, 'pA')
            app.Current_Event_Axes = uiaxes(app.UIFigure);
            title(app.Current_Event_Axes, 'Current Event')
            xlabel(app.Current_Event_Axes, 'msec')
            ylabel(app.Current_Event_Axes, 'pA')
            app.AcceptButton = uibutton(app.UIFigure, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.RejectButton = uibutton(app.UIFigure, 'push');
            app.RejectButton.ButtonPushedFcn = createCallbackFcn(app, @RejectButtonPushed, true);
            app.RejectButton.Text = 'Reject';
            app.ProgressLabel = uilabel(app.UIFigure);
            app.ProgressLabel.Text = {'Progress'; ''};
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mini_qc(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end