classdef template_match < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        DecaytaumsEditField       matlab.ui.control.NumericEditField
        DecaytaumsEditFieldLabel  matlab.ui.control.Label
        RisetaumsEditField        matlab.ui.control.NumericEditField
        RisetaumsEditFieldLabel   matlab.ui.control.Label
        StartmsEditField          matlab.ui.control.NumericEditField
        StartmsEditFieldLabel     matlab.ui.control.Label
        AcceptButton              matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        mean_psc % mean_psc used to generate the tempalte
        Start % Start of the template PSC
        Decaytau % Decay tau, in ms
        Risetau % Rise tau, in ms
        psc_flag %-1 of EPSC, 1 for IPSC
        template %eventual output template
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mean_psc, psc_flag)
            app.mean_psc = mean_psc;
            app.psc_flag = psc_flag;
            app.Start = 7;
            app.Decaytau = 10;
            app.Risetau = 1;
            app.template = app.psc_flag*multiexponential_psc(app.Start,app.Risetau,app.Decaytau);
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.mean_psc,'-k');
            plot(app.UIAxes,app.template,'-r');

        end

        % Value changed function: StartmsEditField
        function StartmsEditFieldValueChanged(app, event)
            value = app.StartmsEditField.Value;
            app.Start = value; %assign the value
            app.template = app.psc_flag*multiexponential_psc(app.Start,app.Risetau,app.Decaytau); %re-make the template
            cla(app.UIAxes);
            plot(app.UIAxes,app.mean_psc,'-k'); %re-plot the overlay
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.template,'-r');
        end

        % Value changed function: DecaytaumsEditField
        function DecaytaumsEditFieldValueChanged(app, event)
            value = app.DecaytaumsEditField.Value;
            app.Decaytau = value; %assign the value
            app.template = app.psc_flag*multiexponential_psc(app.Start,app.Risetau,app.Decaytau); %re-make the template
            cla(app.UIAxes);
            plot(app.UIAxes,app.mean_psc,'-k'); %re-plot the overlay
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.template,'-r');
        end

        % Value changed function: RisetaumsEditField
        function RisetaumsEditFieldValueChanged(app, event)
            value = app.RisetaumsEditField.Value;
            app.Risetau = value; %assign the value
            app.template = app.psc_flag*multiexponential_psc(app.Start,app.Risetau,app.Decaytau); %re-make the template
            cla(app.UIAxes);
            plot(app.UIAxes,app.mean_psc,'-k'); %re-plot the overlay
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.template,'-r');
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            assignin('base','template',app.template); %export the threshold to the base workspace
            assignin('base','Decaytau',app.Decaytau);
            assignin('base','Risetau',app.Risetau);
            closereq(); %exit out of the threshold detection
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AcceptButton = uibutton(app.LeftPanel, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.StartmsEditFieldLabel = uilabel(app.LeftPanel);
            app.StartmsEditFieldLabel.Text = 'Start (ms)';
            app.StartmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StartmsEditField.ValueChangedFcn = createCallbackFcn(app, @StartmsEditFieldValueChanged, true);
            app.StartmsEditField.Value = 10;
            app.RisetaumsEditFieldLabel = uilabel(app.LeftPanel);
            app.RisetaumsEditFieldLabel.Text = 'Rise tau (ms)';
            app.RisetaumsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RisetaumsEditField.ValueChangedFcn = createCallbackFcn(app, @RisetaumsEditFieldValueChanged, true);
            app.RisetaumsEditField.Value = 1;
            app.DecaytaumsEditFieldLabel = uilabel(app.LeftPanel);
            app.DecaytaumsEditFieldLabel.Text = 'Decay tau (ms)';
            app.DecaytaumsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DecaytaumsEditField.ValueChangedFcn = createCallbackFcn(app, @DecaytaumsEditFieldValueChanged, true);
            app.DecaytaumsEditField.Value = 10;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Template Overlay')
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'Norm. Amp.')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = template_match(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end